classdef EEG_MEG_ANALYSIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateEventFiletsvButton        matlab.ui.control.Button
        ConverttoBIDSButton             matlab.ui.control.Button
        ProvenancefileButton            matlab.ui.control.Button
        ExporttoEDFButton               matlab.ui.control.Button
        TESTINGButton                   matlab.ui.control.Button
        EventsDetectedPanel             matlab.ui.container.Panel
        HistoryPanel                    matlab.ui.container.Panel
        MEGHyperlink                    matlab.ui.control.Hyperlink
        EEGHyperlink                    matlab.ui.control.Hyperlink
        ProtocolPanel                   matlab.ui.container.Panel
        DeleteProtocolButton            matlab.ui.control.Button
        LoadProtocolButton              matlab.ui.control.Button
        CreateProtocolButton            matlab.ui.control.Button
        SubjectStudyPanel               matlab.ui.container.Panel
        ReviewRawFilesButton            matlab.ui.control.Button
        DeleteStudyButton               matlab.ui.control.Button
        CreateSubjectButton             matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UpdateEventsButton              matlab.ui.control.Button
        AddTagButton                    matlab.ui.control.Button
        TypeofanalysisDropDownLabel     matlab.ui.control.Label
        TypeofanalysisDropDown          matlab.ui.control.DropDown
        CreateFileHistoryButton         matlab.ui.control.Button
        OpenBrainstormtoVisualizeGraphsButton  matlab.ui.control.Button
        FrequencyAnalysisPanel          matlab.ui.container.Panel
        PowerSpectrumDensityButton      matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        NotchFilterButton               matlab.ui.control.Button
        TabGroup1                       matlab.ui.container.TabGroup
        EEGTabGr1                       matlab.ui.container.Tab
        ImportEventsButton              matlab.ui.control.Button
        DetectHeartbeatsButton          matlab.ui.control.Button
        ProjectElectrodesonScalpButton  matlab.ui.control.Button
        RefineRegistrationButton        matlab.ui.control.Button
        AddEEGPositionsButton           matlab.ui.control.Button
        MEGTabGr1                       matlab.ui.container.Tab
        ConvertEpochstoContinueButton   matlab.ui.control.Button
        SelectDeselectallButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        EEGTabGr2                       matlab.ui.container.Tab
        ICAButton                       matlab.ui.control.Button
        AverageReferenceButton          matlab.ui.control.Button
        MEGTabGr2                       matlab.ui.container.Tab
        SSPButton                       matlab.ui.control.Button
        DetectArtifactButton            matlab.ui.control.Button
        RemoveSimultaneousEventsButton  matlab.ui.control.Button
        DeleteEventsButton              matlab.ui.control.Button
        RenameEventsButton              matlab.ui.control.Button
        CurrentSubjectLabel             matlab.ui.control.Label
        CurrentSubjectEditField         matlab.ui.control.EditField
        CurrentProtocolLabel            matlab.ui.control.Label
        CurrentProtocolEditField        matlab.ui.control.EditField
        UpdateTreeButton                matlab.ui.control.Button
        ChangeCurrentSubjectButton      matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
    end

    
    properties (Access = private)
        
        % Global variables initialized as empty.
        
        AnalysisType = "";
        SensorType = "";
        FileFormat = "";
        AnatomyFileFormat = "";
        ProtocolPath = "";
        StudentFolderPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/";
        BrainstormPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/toolboxes/brainstorm3";
        bs_dbPath = "/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db"
        
    end
    
    methods (Access = private)
        
        function []  = switchAnalysisTab(app, trigger)   
            
            switch trigger
                case "TabGroup1"
                    analysisType = app.TabGroup1.SelectedTab.Title;
                case "TabGroup2"
                    analysisType = app.TabGroup2.SelectedTab.Title;
                case "DropDown"
                    analysisType = app.TypeofanalysisDropDown.Value;
                case "EEG"
                    analysisType = 'EEG';
                case "MEG"
                    analysisType = 'MEG';
            end

            switch analysisType
                case 'EEG'
                    app.TabGroup1.SelectedTab = app.EEGTabGr1;
                    app.TabGroup2.SelectedTab = app.EEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'EEG';
                    
                    app.AnalysisType = 'EEG';
                    app.SensorType = 'EEG';
                    app.FileFormat = 'EEG-DELTAMED';    %EEG-BRAINAMP
                    app.AnatomyFileFormat = 'FreeSurfer-fast';

                case 'MEG'
                    app.TabGroup1.SelectedTab = app.MEGTabGr1;
                    app.TabGroup2.SelectedTab = app.MEGTabGr2;
                    app.TypeofanalysisDropDown.Value = 'MEG';

                    app.AnalysisType = 'MEG';
                    app.SensorType = 'MEG';
                    app.FileFormat = 'CTF';
                    app.AnatomyFileFormat = 'FreeSurfer';
            end 
        end

        function Infos = getCurrentInformations(app)
            % Output: Structure with the current informations.
                
            Infos.CurrentSubject = bst_get('Subject');
            Infos.ProtocolInfo = bst_get('ProtocolInfo');
            Infos.Studies = bst_get('ProtocolStudies');
            Infos.Subjects =  bst_get('ProtocolSubjects');
            Infos.CurrentStudy = bst_get('Study');
            
            assignin("base", "Infos", Infos);
            
            % Check if there is an empty field.
            index = find(structfun(@isempty, Infos), 1);
            if isempty(index)
                return
            end
            
            switch index
                case 1
                    errorMessage = 'CurrentSubject field is empty';
                case 2
                    errorMessage = 'ProtocolInfo field is empty';
                case 3
                    errorMessage = 'ProtocolStudies field is empty';
                case 4
                    errorMessage = 'ProtocolSubjects field is empty';   
            end
            
            if any(structfun(@isempty, Infos))
                uialert(app.UIFigure, errorMessage, 'Error');
            end
            
            return
            
        end

        function [] = updateTree(app)
            
            Infos = app.getCurrentInformations;
            
            % Delete current Tree
            app.Tree.Children.delete;
            
            % Create a temporary node
            temporaryNode = uitreenode(app.Tree);
            temporaryNode.Text = "Temporary Node";
            
            % Iterate on all Studies in Protocol            
            for i = 1:length(Infos.Studies.Study)
                % Get Subject Nodes already in Tree
                subjectNodes = app.Tree.Children;
                subjectNames = {subjectNodes.Text};
                
                % Get Subject Name of Study
                subjectNameChar = strsplit(Infos.Studies.Study(i).BrainStormSubject, '/');
                subjectNameString = convertCharsToStrings(subjectNameChar{1});
                
                % Check if Subject Name is in Subject Nodes
                index = find(contains(subjectNames,subjectNameString));
                if isempty(index)
                    % Create Subject Node in Tree
                    nodeSubject = uitreenode(app.Tree);
                    nodeSubject.Text = subjectNameString;                          
                else
                    % Get Subject node from Tree
                    nodeSubject = subjectNodes(index);
                end  
                % Create Study Node in Subject Node
                nodeStudy = uitreenode(nodeSubject);
                nodeStudy.Text = Infos.Studies.Study(i).Name; 
            end
            % Delete Temporary Node
            temporaryNode.delete      
        end

        function sFiles = selectedStudies(app)
            
            % Output : cell containing paths to Raw Files (data_raw...) copied in Protocol Folder            
            
            % Get checked Nodes
                
            sStudies_checked = app.Tree.CheckedNodes;
            
            sFiles = cell(1, length(sStudies_checked));
            
            for i = 1:length(sStudies_checked)
                
                % Remove Subject Nodes that are checked
                if class(sStudies_checked(i).Parent) == "matlab.ui.container.CheckBoxTree"
                    continue
                end
                
                % Concatenation to create File Path
                if contains(sStudies_checked(i).Text, 'intra') || contains(sStudies_checked(i).Text, 'default')
                   study_folder = strcat(sStudies_checked(i).Parent.Text, '/', sStudies_checked(i).Text, '/', 'brainstormstudy.mat');
                else
                    study_folder = strcat(sStudies_checked(i).Parent.Text, '/', sStudies_checked(i).Text,...
                                         '/data_0raw_', sStudies_checked(i).Text(5:end), '.mat');
                end
                
        
                % Save path in sFiles
                sFiles{i} = study_folder;
                
            end
            sFiles = sFiles(~cellfun(@isempty, sFiles));
            
            if isempty(sFiles)
                uialert(app.UIFigure, 'There no study selected, this might cause an error!', 'Error');
                uiwait(gcbf)
            end
        end

        function [] = selectAllStudies(app)
            
            if isempty(app.Tree.CheckedNodes)
                app.Tree.CheckedNodes = app.Tree.Children;
            else
                app.Tree.CheckedNodes = [];
            end
            
        end

        function [] = updateCurrentProtocolCurrentSubject(app)

            Infos = app.getCurrentInformations;

            app.CurrentProtocolEditField.Value = Infos.ProtocolInfo.Comment;
            
            if ~isempty(Infos.CurrentSubject)
                app.CurrentSubjectEditField.Value = Infos.CurrentSubject.Name;
            end
            
            app.ProtocolPath = strcat('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/', Infos.ProtocolInfo.Comment, '/');
            
            
        end
       
        function Path = getFilePath(app, title, type, extension)
            % Input:    title (str): Window title
            %           type (str): Type of file (data or anatomy)
            
            % Output: FilePath (cell)
            
            if ~exist('extension','var')
                 % extension not given
                  extension = '*';
             end
    
            startingFolder = app.StudentFolderPath;            

            if type == "data" && app.AnalysisType == "MEG"
                folder = uigetdir(startingFolder, title);
                % Keep only data files (.ds)
                FilesName = dir([folder filesep '*.' 'ds']);
                
                % Saving FileName in a variable (Path)
                Path = cell(1, length(FilesName));
                for i = 1:length(FilesName)
                    Path(i) = {strcat(folder, '/', FilesName(i).name)};
                end 
            
            elseif type == "data" && app.AnalysisType == "EEG"
                [File, folderPath] = uigetfile(extension, title);
                Path = fullfile(folderPath, File);

                
            elseif type == "anatomy"
                Path = uigetdir(startingFolder, title);
                
            elseif type == "studentFolder"
                Path = uigetdir(startingFolder, title);
            end
            
        end
  
        function RawImport = reviewRawFiles(app, RawFilePath, ChannelAlign)
            % Input:    RawFilePath (cell): Path to raw files
            %           FileFomat (char): File format
            %           ChannelAlign (double): 0 or 1
            
            % Output:   sFiles (struct): sFiles of raw Files

            Infos = app.getCurrentInformations;
            
            SubjectName = Infos.CurrentSubject.Name;
       
            RawImport = bst_process('CallProcess', 'process_import_data_raw', [], [], ...
                                        'subjectname',  SubjectName, ...
                                        'datafile',     {RawFilePath, app.FileFormat}, ...       
                                        'channelreplace', 1, ...
                                        'channelalign', ChannelAlign); % Align channel on MRI using fiducial points

        end
     
        function [] = importAnatomy(app, SubjectName, AnatomyPath)
            
            if app.AnalysisType == "MEG"
                
                bst_process('CallProcess', 'process_import_anatomy', [], [], ...
                        'subjectname', SubjectName, ...
                        'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
                        'nvertices', 15000, ...
                        'nas', [127, 213, 139], ...
                        'lpa', [52, 113, 96], ...
                        'rpa', [202, 113, 91], ...
                        'ac', [127 119 149], ...
                        'pc', [128 93 141], ...
                        'ih', [131 114 206]);
                
            elseif app.AnalysisType == "EEG"
                
                bst_process('CallProcess', 'process_import_anatomy', [], [], ...
                        'subjectname', SubjectName, ...
                        'mrifile', {AnatomyPath, app.AnatomyFileFormat}, ...
                        'nvertices', 15000);
            end
        end

        function [] = detectArtifact(app, sFiles, type, channel, name)
            
            if type == "cardiac"
                bst_process('CallProcess', 'process_evt_detect_ecg', sFiles, [], ...
                'channelname', channel, ...
                'timewindow',  [], ...
                'eventname',   name);
                
            elseif type == "blink"
                bst_process('CallProcess', 'process_evt_detect_eog', sFiles, [], ...
                'channelname', channel, ...
                'timewindow',  [], ...
                'eventname',   name);
                
            else
                choice = ["YES", "NO"];
                
                repL = uiconfirm(app.UIFigure, 'Do you want to detect artifact of low frequency (eye/teeth movement) ?', ...
                                'What to detect?', 'Options', choice);
               
                repH = uiconfirm(app.UIFigure, 'Do you want to detect artifact of high frequency (muscle movement/sensor artifacts) ?', ...
                                'What to detect?', 'Options', choice);
                  
                LowFreq = 0;
                if repL == choice{1}
                    LowFreq = 1;
                end
                
                HighFreq = 0;
                if repH == choice{1}
                    HighFreq = 1;
                end

                bst_process('CallProcess', 'process_evt_detect_badsegment', sFiles, [], ...
                    'timewindow',  [], ...
                    'sensortypes', 'MEG', ...
                    'threshold',   3, ...
                    'isLowFreq',   LowFreq, ...   % 0 ou 1: detect eyes/teeth movements
                    'isHighFreq',  HighFreq);      % 0 ou 1: detect muscular/sensor artifacts
            end   
        end
        
        function [sEvents, all_events] = getEvents(app, sFiles)
            % Input:    sFiles (struc)
            
            % Output:   [Structure with the Studies as Key and the Event
            %           File as Value, cell of all events]
            
            %Infos = app.getCurrentInformations;
            %cd(app.bs_dbPath);
            
            all_events = cell(1, length(sFiles));
            compt = 1;
            
            for i = 1:length(sFiles)
                directory = strcat(app.ProtocolPath, '/data/', sFiles{i});
                file = load(directory);
                studyName = app.getStudyNameFromsFile(sFiles{i});
                %field = split(sFiles{i},'/');
                %sEvents.(field{2}(2:end)) = file.F.events;
                sEvents.(studyName) = file.F.events;
                
                for j = 1:length(file.F.events)
                    if ~any(strcmp(all_events, file.F.events(j).label))
                        all_events{compt} = file.F.events(j).label;
                        compt = compt + 1;
                    end
                end
            end
            
            %[~, index] = max(cellfun('length', cell_events));
            %all_events = cell_events{index};
        end
        
        function [] = createHitoryHlink(app, linkName, sFile, explanation)
            
            % Create Hyperlink
            hLinkPanel = app.HistoryPanel.Children;
            
            hlink = uihyperlink(app.HistoryPanel);
            hlink.Text = linkName;
            width = 200;
            height = 20;
            
            % Hyperlink Position
            positionPanel = app.HistoryPanel.Position;
            if isempty(hLinkPanel)
                position = [5 positionPanel(4)-height*2 width height];
            else
                position = hLinkPanel(1).Position + [0 -height 0 0];     
            end
            
            hlink.Position = position;
            
            st = dbstack;
            namestr = st.name;  % Name of the triggering function
            
            cd(app.StudentFolderPath);
            
            % Create file
            fileName = "history" + (length(hLinkPanel)+1); % This is to have unique link name
            extension = ".m";
            file = fopen(fileName+extension, 'W');
         
            fprintf(file, "%% " + explanation + newline + newline);    
            
            fprintf(file, "%% The function «" + namestr + "» was executed on the following studies (" + ...
                            length(sFile) + "): " + newline + newline);
            
            fprintf(file, "Studies = { ..." + newline);  
            for i = 1:length(sFile)
                fprintf(file, "'" + sFile{i} + "', ..." + newline);
                if i == length(sFile)
                    fprintf(file, "};");
                end
            end  
            
            fclose(file);
            publish(fileName);          
            
            hlink.URL = "file:///" + app.StudentFolderPath + "/html/" + fileName + ".html";

            delete(fileName+extension);
        end
        
        function [] = createEventHlink(app, sFiles)
            
            for i = 1:length(sFiles)
                % Create Hyperlink
                hLinkPanel = app.EventsDetectedPanel.Children;
                
                hlink = uihyperlink(app.EventsDetectedPanel);
                    fieldname = split(sFiles{i},'/');
                    fieldname = fieldname{2}(2:end);    % Used later as key to access Event Structure
                hlink.Text = fieldname;
                width = 200;
                height = 20;
                
                % Hyperlink Position
                positionPanel = app.EventsDetectedPanel.Position;
                if isempty(hLinkPanel)
                    position = [5 positionPanel(4)-height*2 width height];
                else
                    position = hLinkPanel(1).Position + [0 -height 0 0];     
                end
                
                hlink.Position = position;
                
                [events, ~] = app.getEvents(sFiles);
                
                cd(app.StudentFolderPath);

                % Create file
                fileName = "events" + (length(hLinkPanel)+1); % This is to have unique link name
                extension = ".m";
                file = fopen(fileName+extension, 'W');
                
                fprintf(file, "%% " + "The following Events have been detected: " + newline + newline);    
                
                fprintf(file, "Events = { ..." + newline);
               
                for j = 1:length(events.(fieldname))
                    fprintf(file, "'" + events.(fieldname)(j).label + "', ..." + newline);
                    if j == length(events.(fieldname))
                        fprintf(file, "};");
                    end
                end  
                
                fclose(file);
                publish(fileName);          
                
                hlink.URL = "file:///" + app.StudentFolderPath + "/html/" + fileName + ".html";
                
                delete(fileName+extension); 
            
            end
        end
        
        function [] = createJsonEventFile(app, sFiles, dest)
            
            %cd(app.StudentFolderPath);
            
            [~, all_events] = app.getEvents(sFiles);
            
            for i = 1:length(all_events)
                new_event = strrep(all_events{i}, '-', '_');
                s__.(new_event) = 'Description';
            end
            
            s_.LongName = 'Event category';
            s_.Description = 'Indicator of type of action that is expected';
            s_.Levels = s__;
            
            s.trial_type = s_;
            
            fileName = 'event_metadata.json';
            app.saveAsJSON(s, fileName)
            copyfile(fileName, dest);
            delete(fileName);
        end  
       
        function [] = createTsvFile(app, sFiles, dest)

            events = app.getEvents(sFiles);
            studiesFiles = app.loadFiles(sFiles);
     
            % Loop through all studies
            for i = 1:length(events)
                
                % Initialiaze tsvFile
                nb_events = 2; % Arbitrary number...
                tsvFile = strings([nb_events,3]);
                compt = 1;
                tsvFile(compt,:) = ["onset" "duration" "trial type"];
                compt = compt + 1;
                
                % Get the study name to access the corresponding events in
                % structure
                studyName = app.getStudyNameFromsFile(sFiles{i});
                %studyName = split(sFiles{i}, '/');
                %studyName = studyName{2}(2:end);
            
                % Loop through all event types
                for j = 1:length(events.(studyName))
                    
                    % Loop through all time (each occurence of an event)
                    for k = 1:length(events.(studyName)(j).times)

                        tsvFile(compt,:) = [events.(studyName)(j).times(k) 0 string((events.(studyName)(j).label))];
                        compt = compt + 1;
                    end  
                end              
                
                % Get date of study
                chars = split(studiesFiles.(studyName){1}.DateOfStudy, '-');             
                months = struct('Jan', '1', 'Feb', '2', 'Mar', '3', 'Apr', '4', 'May', '5', 'Jun', '6', 'Jul', '7', 'Aug', '8', 'Sep', '9', 'Oct', '10', 'Nov', '11', 'Dec', '12');
                date = strcat(chars{3}, months.(chars{2}), chars{1});
                BIDS_folder = strcat(dest, '/sub-0001/ses-', date, '/', lower(app.AnalysisType), '/');
                cd(BIDS_folder);
                
                %cd(app.StudentFolderPath);
                fileName = strcat(studyName, '.txt');
                writematrix(tsvFile,fileName, 'Delimiter', 'tab');
                
                % Create Json file
                app.createJsonEventFile(sFiles, BIDS_folder);
                            
            end
           
            %uiconfirm(app.UIFigure, '.tsv file and .json file created!', 'Files created');
            disp('TSV AND JSON FILE CREATED');
        end
        
        function studiesFiles = loadFiles(app, sFiles)
            % Return: [brainstormstudy, study.mat] for each study
            
            Infos = app.getCurrentInformations;
            
            for i = 1:length(sFiles)
                sFiles_split = split(sFiles{i}, '/');
                Dir = app.bs_dbPath + '/' + Infos.ProtocolInfo.Comment + '/data/' + sFiles_split{1} + '/' + sFiles_split{2};
                cd(Dir)
                studiesFiles.(sFiles_split{2}(2:end)) = {load('brainstormstudy.mat'), load(sFiles_split{3})};
            end            
        end
        
        function [] = convertToBIDS(app, sFiles)
            
            for i = 1:length(sFiles)
                
                studyName = app.getStudyNameFromsFile(sFiles{i});
                
                RawFiles = convertStringsToChars(app.StudentFolderPath + "/" + studyName);
                
                mkdir(RawFiles);
                
                bst_process('CallProcess', 'process_export_bids', sFiles{i}, [], ...
                    'bidsdir',       {RawFiles, 'BIDS'}, ...
                    'subscheme',     2, ...  % Number index
                    'sesscheme',     1, ...  % Acquisition date
                    'emptyroom',     'emptyroom, noise', ...
                    'defacemri',     0, ...
                    'overwrite',     0, ...
                    'powerline',     2, ...  % 60 Hz
                    'dewarposition', 'Upright', ...
                    'eegreference',  'Cz', ...
                    'edit',          struct(...
                         'ProjName',    [], ...
                         'ProjID',      [], ...
                         'ProjDesc',    [], ...
                         'Categories',  [], ...
                         'JsonDataset', ['{' 10 '  "License": "PD"' 10 '}'], ...
                         'JsonMeg',     ['{' 10 '  "TaskDescription": "My task"' 10 '}']));
                
                
                tic;
                app.exportToEDF(sFiles{i}, RawFiles);
                toc;
                
                disp('EDF SAVED!');
                %uiconfirm(app.UIFigure, "EDF Saved!", "Edf Saved!");
                
                app.createTsvFile(sFiles(1), RawFiles);

                %uiconfirm(app.UIFigure, "All Done", "All Done");
                
            end
            disp('ALL DONE!');
        end
        
        function [] = exportToEDF(app, sFiles, dest)          
            
            sFilesSplit = split(sFiles, '/');
            studyName = sFilesSplit{2};
            subjectName = sFilesSplit{1};
            %channelFile = app.bs_dbPath + "/" + Infos.ProtocolInfo.Comment + "/data/" + sFilesSplit{1} + "/" + sFilesSplit{2} + "/";
            channelFile = app.ProtocolPath + "data/" + subjectName + "/" + studyName + "/";
            
            all_files = dir(channelFile);
            
            for i = 1:length(all_files)
                if contains(all_files(i).name, 'channel')
                    channelFile = channelFile + all_files(i).name;
                    break
                end
            end
            
            dest = strcat(dest, '/', studyName(2:end), '.edf');
            
            export_data(sFiles, channelFile, dest, []);
        end
       
        function [] = createProvenanceFile(app)
            activity.id = 'init';
            activity.label = 'do some init';
            activity.command = 'button pushed';
            activity.startedAtTime = '2021-01-01T23:59:59';
            
            provenance.activity = activity;
            
            fileName = 'template_provenance.json';
            app.saveAsJSON(provenance, fileName)
            copyfile(fileName, app.StudentFolderPath);
            delete(fileName);
        end
    end
    
    methods(Static)
        
        function studyName = getStudyNameFromsFile(sFile)
            sFileSplit = split(sFile, '/');
            studyName = sFileSplit{2}(2:end); 
        end
        
        % The next function has been taken here: 
        % https://www.mathworks.com/matlabcentral/fileexchange/77284-structure-and-object-to-json?s_tid=srchtitle
        
        function saveAsJSON(data, jsonFileName)
        % saves the values in the structure 'data' to a file in JSON format.
        % Based on the work of Lior Kirsch at: https://uk.mathworks.com/matlabcentr
        % al/fileexchange/50965-structure-to-json
        % 
        % Modification by Arthur Y.C. Liu 24/06/2020
        % 
        % Example:
        %     data.name = 'chair';
        %     data.color = 'pink';
        %     data.eye = eye(3);
        %     data.metrics.imageSize = [1920; 1080];
        %     data.metrics.height = 0.3;
        %     data.metrics.width = 1.3;
        %     saveJSONfile(data, 'out.json');
        % 
        % Output 'out.json':
        % {
        % 	"name" : "chair",
        % 	"color" : "pink",
        % 	"eye" : 
        % 	[
        % 		1,
        % 		0,
        % 		0,
        % 		0,
        % 		1,
        % 		0,
        % 		0,
        % 		0,
        % 		1
        % 	],
        % 	"metrics" : 
        % 	{
        % 		"imageSize" : 
        % 		[
        % 			1920,
        % 			1080
        % 		],
        % 		"height" : 0.3,
        % 		"width" : 1.3
        % 	}
        % }
            fid = fopen(jsonFileName,'w');
            if isobject(data)
                data = toStruct(data);
            end
            writeElement(fid, data, '', true);
            fprintf(fid,'\n');
            fclose(fid);
        
        
        function writeElement(fid, data, tabs, isFirstLine)
            namesOfFields = fieldnames(data);
            numFields = length(namesOfFields);
            tabs = sprintf('%s\t', tabs);
            if nargin == 4
                if isFirstLine
                    fprintf(fid,'%s{\n%s', tabs(1:end-1), tabs);
                end
            else
                fprintf(fid,'\n%s{\n%s', tabs(1:end-1), tabs);
            end
            for i = 1:numFields
                currentField = namesOfFields{i};
                currentElementValue = data.(currentField);
                writeSingleElement(fid, currentField, currentElementValue, tabs);
                if i == numFields
                    fprintf(fid,'\n%s}',  tabs(1:end-1)); 
                else
                    fprintf(fid,',\n%s', tabs);
                end
            end
        end
        
        function writeSingleElement(fid, currentField, currentElementValue, tabs)
        % if this is an array/matrix and not a string then iterate on every
        % element, if this is a single element write it
            if ~isstruct(currentElementValue) &&...
                    length(currentElementValue) > 1 && ~ischar(currentElementValue)  
                fprintf(fid,'"%s" : \n%s[\n%s\t',currentField, tabs, tabs);
                tabs = sprintf('%s\t', tabs);
                valLength = length(currentElementValue(:));
                for m = 1:valLength
                    fprintf(fid,'%g' , currentElementValue(m));
                    if m == valLength
                        fprintf(fid,'\n%s]', tabs(1:end-1));
                    else
                        fprintf(fid,',\n%s', tabs);
                    end
                end    
            elseif isstruct(currentElementValue) &&...
                    length(currentElementValue) > 1 && ~ischar(currentElementValue)
                fprintf(fid,'"%s" : \n%s[',currentField, tabs);
                tabs = sprintf('%s\t', tabs);
                valLength = length(currentElementValue(:));
                for m = 1:valLength
                    writeElement(fid, currentElementValue(m), tabs);
                    if m == valLength
                        fprintf(fid,'\n%s]', tabs(1:end-1));
                    else
                        fprintf(fid,',');
                    end
                end
            elseif isstruct(currentElementValue)
                fprintf(fid,'"%s" : ',currentField);
                writeElement(fid, currentElementValue, tabs);
            elseif isnumeric(currentElementValue) || islogical(currentElementValue)
                fprintf(fid,'"%s" : %g' , currentField, currentElementValue);
            elseif isempty(currentElementValue)
                fprintf(fid,'"%s" : "null"' , currentField);
            elseif isobject(currentElementValue)
                currentElementValue = toStruct(currentElementValue);
                fprintf(fid,'"%s" : ',currentField);
                writeElement(fid, currentElementValue, tabs);
            else %ischar or something else ...
                fprintf(fid,'"%s" : "%s"' , currentField, currentElementValue);
            end
        end
        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.UIFigure.WindowState = 'maximized';
           
            % Start Brainstorm without interface
            cd(app.BrainstormPath);
            
            if ~brainstorm('status')
                brainstorm nogui;
            end  
            
            choice = ["EEG", "MEG"];
            rep = uiconfirm(app.UIFigure, 'What type of analysis do you want to conduct ?', 'Type of analysis', 'Options', choice);
            app.switchAnalysisTab(rep);
       
            title = "Select the folder that contain your data and your anatomy";
            app.StudentFolderPath = app.getFilePath(title, "studentFolder");   
            
        end

        % Button pushed function: ProvenancefileButton
        function ProvenancefileButtonPushed(app, event)
            app.createProvenanceFile;
            
        end

        % Button pushed function: ExporttoEDFButton
        function ExporttoEDFButtonPushed(app, event)
            sFiles = app.selectedStudies;
            Infos = app.getCurrentInformations;            
            
            sFilesSplit = split(sFiles, '/');
            %channelFile = app.ProtocolPath + 
            channelFile = app.bs_dbPath + "/" + Infos.ProtocolInfo.Comment + "/data/" + sFilesSplit{1} + "/" + sFilesSplit{2} + "/";
                        
            all_files = dir(channelFile);
            
            for i = 1:length(all_files)
                if contains(all_files(i).name, 'channel')
                    channelFile = channelFile + all_files(i).name;
                    break
                end
            end
            
            export_data(sFiles, channelFile, [], []);
            
            uiconfirm(app.UIFigure, '.edf file created!', 'File created');
        end

        % Button pushed function: TESTINGButton
        function TESTINGButtonPushed(app, event)
            % For testing! TESTING TOKEN! 31-08-2021
            cd(app.StudentFolderPath);
            dest = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin/allo.edf';
            tic;
            
            sFiles = {'Subject01/@rawS01_AEF_20131218_01_600Hz_notch/data_0raw_S01_AEF_20131218_01_600Hz_notch.mat'};
            
            channelFile = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/rg/bs_db/TutorialIntroduction/data/Subject01/@rawS01_AEF_20131218_01_600Hz_notch/channel_ctf_acc1.mat';
            
            export_data(sFiles, channelFile, dest, []);
            
            sFiles = {'Subject01/@rawS01_AEF_20131218_02_600Hz_notch/data_0raw_S01_AEF_20131218_02_600Hz_notch.mat'};
            
            export_data(sFiles, channelFile, dest, []);

            toc;
            
            tic;
            sFiles = {'Subject01/@rawS01_AEF_20131218_01_600Hz_notch/data_0raw_S01_AEF_20131218_01_600Hz_notch.mat', ...
                'Subject01/@rawS01_AEF_20131218_02_600Hz_notch/data_0raw_S01_AEF_20131218_02_600Hz_notch.mat'};
            
            export_data(sFiles, channelFile, dest, []);
            toc;
        end

        % Button pushed function: CreateProtocolButton
        function CreateProtocolButtonPushed(app, event)

            title = 'Create Protocol';
            question = 'Name of the protocol to create: ';
            ProtocolName = char(inputdlg(question, title));

            if not(isempty(ProtocolName))
                gui_brainstorm('CreateProtocol', ProtocolName, 0, 0); % 0/1: Use default anatomy / Use default channel
                bst_report('Start');
            end
                      
        end

        % Button pushed function: DeleteProtocolButton
        function DeleteProtocolButtonPushed(app, event)
            
            title = 'Delete Protocol';
            question = 'Name of the protocol to delete: ';
            ProtocolName = char(inputdlg(question, title));
            
            if not(isempty(ProtocolName))
                gui_brainstorm('DeleteProtocol', ProtocolName);
            end
            
        end

        % Button pushed function: LoadProtocolButton
        function LoadProtocolButtonPushed(app, event)
            
            allProtocols = cell(1, 30);    % 30 est choisi au hasard (supérieur au nombre de protocol)
            
            i = 1;
            while true
              bst_set('iProtocol', i);
              protocol = bst_get('ProtocolInfo');
              if isempty(protocol)
                  break
              end
              allProtocols{i} = protocol.Comment;
              i = i + 1;
            end
            allProtocols = allProtocols(~cellfun(@isempty, allProtocols));
            
            index = listdlg('ListString', allProtocols);
            if isempty(index)
                return
            end
            
            protocol_index = bst_get('Protocol', allProtocols{index});
            
            
            % After testing, this is the beat way to switch protocol...
            bst_set('iProtocol', protocol_index)                 
            protocol_to_load = bst_get('ProtocolInfo');
            sProtocolInfo = load(strcat(protocol_to_load.STUDIES, '/protocol.mat'));
            bst_set('ProtocolInfo', sProtocolInfo)
            bst_set('ProtocolInfo', sProtocolInfo.ProtocolInfo)
            bst_set('ProtocolSubjects', sProtocolInfo.ProtocolSubjects)
            bst_set('ProtocolStudies', sProtocolInfo.ProtocolStudies)
            
            app.updateTree
            app.updateCurrentProtocolCurrentSubject
        end

        % Button pushed function: CreateSubjectButton
        function CreateSubjectButtonPushed(app, event)
            
            title = 'Name your subject';
            question = 'Subject Name: ';
            SubjectName = char(inputdlg(question, title));
            
            if isempty(SubjectName)
                return
            end
            
            
            choice = ["Sepecify anatomy folder", "Use default anatomy"]; 
            rep = uiconfirm(app.UIFigure, 'Do you have a specific anatomy folder for your subjet or do you want to use the default anatomy ?', ...
                'Create Subject', 'Options', choice); 
            switch rep
                case choice(1)
                    title = "Choose the anatomy folder";
                    AnatomyPath = app.getFilePath(title, "anatomy");
                    
                    if isempty(AnatomyPath)
                        return
                    end
                                
                    app.importAnatomy(SubjectName, AnatomyPath);
                    
                case choice(2)
                    db_add_subject(SubjectName);

                otherwise
                    return
            end
            
            app.updateTree;
      
        end

        % Button pushed function: ReviewRawFilesButton
        function ReviewRawFilesButtonPushed(app, event)
            
            if app.AnalysisType == "MEG"
                
                choice = ["Data Files", "Noise Files"];
                type = uiconfirm(app.UIFigure, 'Wich type of file do you want to import ?', 'Files to import', 'Options', choice);
                
                switch type
                    case choice(1)         
                        title = "Select the folder containing your data files";
                        ChannelAlign = 1;          
                    case choice(2)               
                        title = "Select the folder containing your noise files";
                        ChannelAlign = 0;
                    otherwise
                        return      
                end
                
                
            elseif app.AnalysisType == "EEG"
                title = "Select your EEG data file with the *.bin extension.";
                ChannelAlign = 0; 
            end
 
            RawFilePath = getFilePath(app, title, "data", "*.bin");
            reviewRawFiles(app, RawFilePath, ChannelAlign)
            
            app.updateTree;

        end

        % Button pushed function: NotchFilterButton
        function NotchFilterButtonPushed(app, event)
            
            choice = ["I am in North America", "I am in Europe"];
            rep = uiconfirm(app.UIFigure, 'Where has your protocol been conducted ?', 'Notch filter', 'Options', choice);

            switch rep
                case choice(1)         
                    frequence = [60 120 180 240];              
                case choice(2)               
                    frequence = [50, 100, 150, 200];
                otherwise
                    return
            end       
            
            sFiles = app.selectedStudies;                          

            bst_process('CallProcess', 'process_notch', sFiles, [], ...
                                    'sensortypes', app.SensorType, ...
                                    'freqlist',    frequence, ...
                                    'cutoffW',     1, ...
                                    'useold',      0, ...
                                    'read_all',    0);
            
            explanation = "The following frequencies will be removed due to the power line frequency: " + num2str(frequence) + " [Hz]";
            app.createHitoryHlink('Notch Filter', sFiles, explanation);
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            title = 'Enter the frequencies cut off';
            
            question = 'Lower cut off frequency (Hz): ';
            LowPass = char(inputdlg(question, title));
            
            question = 'Higher cut off frequency (Hz): ';
            HighPass = char(inputdlg(question, title));
            
            if isempty(HighPass) || isempty(LowPass)
                uialert(app.UIFigure, "Please enter an upper and lower frequency cutoff points.", 'Error')
                return
            end
            
            bst_process('CallProcess', 'process_bandpass', sFiles, [], ...
                'sensortypes', app.SensorType, ...
                'highpass',    HighPass, ...
                'lowpass',     LowPass, ...
                'tranband',    0, ...
                'attenuation', 'strict', ...  % 60dB
                'ver',         '2019', ...  % 2019
                'mirror',      0, ...
                'read_all',    0);
            
            explanation = "The frequencies outside the range [" + LowPass + " " + HighPass + ...
                            "] Hz will be rejected (attenuated). The frequencies within that range will not be affected.";
            
            app.createHitoryHlink('Band-Pass Filter', sFiles, explanation);
        end

        % Button pushed function: PowerSpectrumDensityButton
        function PowerSpectrumDensityButtonPushed(app, event)

            sFiles = app.selectedStudies; 
   
            bst_process('CallProcess', 'process_psd', sFiles, [], ...
            	'timewindow',  [], ...
            	'win_length',  4, ...
            	'win_overlap', 50, ...
            	'clusters',    {}, ...
             	'sensortypes', 'MEG', ...
            	'edit', struct(...
            	'Comment',    'Power', ...
                'TimeBands',  [], ...
            	'Freqs',      [], ...
            	'ClusterFuncTime', 'none', ...
            	'Measure',    'power', ...
            	'Output',     'all', ...
            	'SaveKernel', 0));
            
            explanation = "The Power Spectrum Density (PSD) represents the intensity (amplitude) of every frequency of a signal.";
            app.createHitoryHlink('Power Spectrum Density', sFiles, explanation);
        end

        % Button pushed function: ConvertEpochstoContinueButton
        function ConvertEpochstoContinueButtonPushed(app, event)

            sFiles = app.selectedStudies;
            
            bst_process('CallProcess', 'process_ctf_convert', sFiles, [], ...
                'rectype', 2);  % 2: To Continue
            
            explanation = "The function converts Epochs files to Continue files.";
            app.createHitoryHlink('Conversion Epochs to Continue', sFiles, explanation);
        end

        % Button pushed function: DetectArtifactButton
        function DetectArtifactButtonPushed(app, event)
            choice = ["Cardiac", "Blink", "Other"];
            rep = questdlg('What to detect?', ...
                            'Artifact detection', ...
                            choice(1), choice(2), choice(3), []);
            switch rep
                case choice{1}
                    type = "cardiac";
                    channel = 'ECG';
                    name = 'cardiac';
                case choice{2}   
                    type = "blink";
                    channel = 'VEOG';
                    name = 'blink';
                case choice{3}
                    type = "other";
                    channel = "";
                    name = "";
            end
            
            sFiles = app.selectedStudies; 
            
            app.detectArtifact(sFiles, type, channel, name);
            
            app.createHitoryHlink('Artifact Detection', sFiles);
        end

        % Button pushed function: RenameEventsButton
        function RenameEventsButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            [~, choice] = app.getEvents(sFiles);
            
            index = listdlg('ListString', choice, 'PromptString', 'Rename?');
            
            if length(index) ~= 1
                waitfor(errordlg("Choose one and only one event to rename."))
                return
            end
            
            old_name = choice{index};
            
            title = 'Nouveau nom d''évenement';
            question = 'Nouveau nom?';
            new_name = char(inputdlg(question, title));

            if isempty(new_name)
                waitfor(errordlg("Please enter a new event name."))
                return
            end
                           
            bst_process('CallProcess', 'process_evt_rename', sFiles, [], ...
                        'src',  old_name, ...
                        'dest', new_name);
            
            app.createHitoryHlink('Rename Event', sFiles);
        end

        % Button pushed function: DeleteEventsButton
        function DeleteEventsButtonPushed(app, event)
            sFiles = app.selectedStudies; 
            
            [~, choice] = app.getEvents(sFiles);
            
            index = listdlg('ListString', choice, 'PromptString', 'delete?');
            
            if isempty(index)
                msgbox("Missing event to delete.")
                return
            end
        
            for i = 1:length(index)
           
                bst_process('CallProcess', 'process_evt_delete', sFiles, [], ...
                'eventname', choice{index(i)});       
            end
            
            app.createHitoryHlink('Delete Event', sFiles);
        end

        % Button pushed function: RemoveSimultaneousEventsButton
        function RemoveSimultaneousEventsButtonPushed(app, event)
            sFiles = app.selectedStudies; 
            
            [~, choice] = app.getEvents(sFiles);       
            
            indexR = listdlg('ListString', choice, 'PromptString', 'Remove the event named: ');
            indexT = listdlg('ListString', choice, 'PromptString', 'When it is too close to the event: ');
            
            if length(indexR) > 1 || length(indexT) > 1
                msgbox("Choose only one event.")
                return
            elseif isempty(indexR) || isempty(indexT)
                msgbox("Missing event to remove or to targert.")
                return
            end
            
            remove = choice{indexR};
            target = choice{indexT}; 
                
            bst_process('CallProcess', 'process_evt_remove_simult', sFiles, [], ...
                'remove', remove, ...
                'target', target, ...
                'dt',     0.25, ...
                'rename', 0);
        
            app.createHitoryHlink('Remove Simultaneous Event', sFiles);
        end

        % Button pushed function: SSPButton
        function SSPButtonPushed(app, event)
            sFiles = app.selectedStudies; 
            
            [~, choice] = app.getEvents(sFiles);
            
            index = listdlg('ListString', choice, 'PromptString', 'Which event do you want to use to compute the SSP ?:');
            eventname = choice{index};
 
            bst_process('CallProcess', 'process_ssp_ecg', sFiles, [], ...
                        'eventname',   eventname, ...
                        'sensortypes', app.SensorType, ...
                        'usessp',      1, ...
                        'select',      1);
            
            for i = 1:length(sFiles)     
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(errordlg("Click when you are done choosing"));
            end            
            
            app.createHitoryHlink('SSP', sFiles);
        end

        % Button pushed function: AddEEGPositionsButton
        function AddEEGPositionsButtonPushed(app, event)
            sFiles = app.selectedStudies; 
            
            title = "Select the electrode file (it should have the *.elc extension)";
            file = app.getFilePath(title, "data", "*.elc");
            
            bst_process('CallProcess', 'process_channel_addloc', sFiles, [], ...
                        'channelfile', {file, 'XENSOR'}, ...
                        'usedefault', 1, ...
                        'fixunits', 1, ...
                        'vox2ras', 0);
            
            app.createHitoryHlink('Add EEG Position', sFiles);
            
        end

        % Button pushed function: RefineRegistrationButton
        function RefineRegistrationButtonPushed(app, event)

            sFiles = app.selectedStudies; 

            bst_process('CallProcess', 'process_headpoints_refine', sFiles, []);

            app.createHitoryHlink('Refine Registration', sFiles);
        end

        % Button pushed function: ProjectElectrodesonScalpButton
        function ProjectElectrodesonScalpButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            bst_process('CallProcess', 'process_channel_project', sFiles, []);
            
            app.createHitoryHlink('Project Electrode on Scalp', sFiles);
        end

        % Button pushed function: DetectHeartbeatsButton
        function DetectHeartbeatsButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            type = "cardiac";
            channel = 'ECG';
            name = 'cardiac';
            app.detectArtifact(sFiles, type, channel, name);
            
            app.createHitoryHlink('Detect Heartbeats', sFiles);
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            title = "Select the event file (it should have the *.txt extension)";
            file = app.getFilePath(title, "data", "*.txt");         
            
            title = 'Name the event';
            question = 'Name of the imported events:';
            new_name = char(inputdlg(question, title));
            
            bst_process('CallProcess', 'process_evt_import', sFiles, [], ...
                'evtfile', {file, 'ARRAY-TIMES'}, ...
                'evtname', new_name);
            
            app.createHitoryHlink('Import Events', sFiles);
        end

        % Button pushed function: AverageReferenceButton
        function AverageReferenceButtonPushed(app, event)

            sFiles = app.selectedStudies; 
            
            bst_process('CallProcess', 'process_eegref', sFiles, [], ...
                        'eegref', 'AVERAGE', ...
                        'sensortypes', app.SensorType);
            
             for i = 1:length(sFiles)     
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(errordlg("Click when you are done choosing"));
             end   
             
             app.createHitoryHlink('Average Reference', sFiles);
        end

        % Button pushed function: ICAButton
        function ICAButtonPushed(app, event)

            sFiles = app.selectedStudies; 
                
            title = 'ICA components';
            question = 'How many ICA components (nb electrode / 2): ';
            ICA_components = inputdlg(question, title);

                    
            message = uiconfirm(app.UIFigure, 'This might take a couple a minutes... You have time ti grab a cup of coffee.');
            
            bst_process('CallProcess', 'process_ica', sFiles, [], ...
                        'timewindow', [], ...
                        'eventname', '', ...
                        'eventtime', [-0.1992, 0.1992], ...
                        'bandpass', [0, 0], ...
                        'nicacomp', str2double(ICA_components), ...
                        'sensortypes', 'EEG', ...
                        'usessp', 1, ...
                        'ignorebad', 1, ...
                        'saveerp', 0, ...
                        'method', 1, ...
                        'select', [1 2]);
            
            delete(message)
            
            for i = 1:length(sFiles)     
                view_timeseries(sFiles{i});
                panel_ssp_selection('OpenRaw');
                waitfor(errordlg("Click when you are done choosing"));
            end   
            
            app.createHitoryHlink('ICA', sFiles);
        end

        % Selection change function: TabGroup1
        function TabGroup1SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup1");
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            app.switchAnalysisTab("TabGroup2");
        end

        % Value changed function: TypeofanalysisDropDown
        function TypeofanalysisDropDownValueChanged(app, event)
            app.switchAnalysisTab("DropDown");
        end

        % Button pushed function: 
        % OpenBrainstormtoVisualizeGraphsButton
        function OpenBrainstormtoVisualizeGraphsButtonPushed(app, event)
            cd(app.BrainstormPath);
            brainstorm;
        end

        % Button pushed function: SelectDeselectallButton
        function SelectDeselectallButtonPushed(app, event)
            app.selectAllStudies;
        end

        % Button pushed function: ChangeCurrentSubjectButton
        function ChangeCurrentSubjectButtonPushed(app, event)

            Infos = app.getCurrentInformations;
            
            % Liste de tous les sujets actuels
            liste = cell(1, length(Infos.Subjects.Subject));
            for i = 1:length(Infos.Subjects.Subject)
               liste{i} = Infos.Subjects.Subject(i).Name;
            end

            index = listdlg('ListString', liste);
            if isempty(index)
                return
            end
            
            Nom = liste{index};
            [sSubject, iSubject] = bst_get('Subject', Nom);                  
            bst_set('Subject', iSubject, sSubject)
 
            app.updateCurrentProtocolCurrentSubject;

        end

        % Button pushed function: DeleteStudyButton
        function DeleteStudyButtonPushed(app, event)
            
            %A REVOIR!
            
            % Choix des types de données a importer.
            choice = ["Delete File", "Delete Folder (recommended)", "Delete Subject"];
            type = uiconfirm(app.UIFigure, "Which file do you want to delete ?", "Files to delete", 'Options', choice);
            
            switch type
                case choice(1)
                    del = 1;
                case choice(2)        
                    del = 2;
                case choice(3)
                    del = 3;
                otherwise
                    return
            end
            
            try
               sFiles = app.selectedStudies; 
            catch MyException
                waitfor(msgbox(MyException.message));
                return
            end
            
            
            bst_process('CallProcess', 'process_delete', sFiles, [], ...
                'target', del); % 1: fichier 2: folder 3: subjects
        end

        % Button pushed function: UpdateTreeButton
        function UpdateTreeButtonPushed(app, event)
            app.updateTree;
            app.updateCurrentProtocolCurrentSubject;
        end

        % Button pushed function: UpdateEventsButton
        function UpdateEventsButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.createEventHlink(sFiles);
        end

        % Button pushed function: AddTagButton
        function AddTagButtonPushed(app, event)
            sFiles = app.selectedStudies;
            
            title = 'Add tag';
            question = 'Tag name to add: ';
            Tag = char(inputdlg(question, title));
            
            choice = ["Add Tag to the file Name", "Add tag to the file path"];
                
            rep = uiconfirm(app.UIFigure, 'where do you want to add the tag ?', ...
                            'Add Tag', 'Options', choice);
            
            switch rep
                case choice{1}
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        1);
                    
                case choice{2}
                    bst_process('CallProcess', 'process_add_tag', sFiles, [], ...
                        'tag',           Tag, ...
                        'output',        2);
            end
        end

        % Button pushed function: ConverttoBIDSButton
        function ConverttoBIDSButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.convertToBIDS(sFiles);
        end

        % Button pushed function: CreateFileHistoryButton
        function CreateFileHistoryButtonPushed(app, event)
            sFiles = app.selectedStudies;
            
            history = struct;
            
            for i = 1:length(sFiles)
                studyFile = load(strcat(app.ProtocolPath, '/data/', sFiles{i}));
                fileName = strsplit(sFiles{i}, '/');
                fileName = fileName{end}(1:end-4);
                history.(fileName) = studyFile.History;                           
            end
            
            fileName = "History";
            
            save(app.StudentFolderPath + "/" + fileName + ".mat", "history");
            
            uiconfirm(app.UIFigure, 'The history file has been save', 'File Saved');
        end

        % Button pushed function: CreateEventFiletsvButton
        function CreateEventFiletsvButtonPushed(app, event)
            sFiles = app.selectedStudies;
            app.createTsvFile(sFiles);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            rep = uiconfirm(app.UIFigure, 'Do you want to close the app ?', 'Close request');
            
            if rep == "OK"
                %cd(app.StudentFolderPath)
                %delete('html/*.html')
            
                delete(app) 
            end
                       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ChangeCurrentSubjectButton = uibutton(app.UIFigure, 'push');
            app.ChangeCurrentSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeCurrentSubjectButtonPushed, true);
            app.ChangeCurrentSubjectButton.Text = 'Change Current Subject';
            app.UpdateTreeButton = uibutton(app.UIFigure, 'push');
            app.UpdateTreeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTreeButtonPushed, true);
            app.UpdateTreeButton.Text = 'Update Tree';
            app.CurrentProtocolEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentProtocolEditField.Tag = 'CurrentProto';
            app.CurrentProtocolEditField.Editable = 'off';
            app.CurrentProtocolLabel = uilabel(app.UIFigure);
            app.CurrentProtocolLabel.Text = 'Current Protocol:';
            app.CurrentSubjectEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentSubjectEditField.Tag = 'CurrentSubject';
            app.CurrentSubjectEditField.Editable = 'off';
            app.CurrentSubjectLabel = uilabel(app.UIFigure);
            app.CurrentSubjectLabel.Text = 'Current Subject:';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EEGTabGr2 = uitab(app.TabGroup2);
            app.EEGTabGr2.Title = 'EEG';
            app.AverageReferenceButton = uibutton(app.EEGTabGr2, 'push');
            app.AverageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageReferenceButtonPushed, true);
            app.AverageReferenceButton.Text = 'Average Reference';
            app.ICAButton = uibutton(app.EEGTabGr2, 'push');
            app.ICAButton.ButtonPushedFcn = createCallbackFcn(app, @ICAButtonPushed, true);
            app.ICAButton.Text = 'ICA';
            app.MEGTabGr2 = uitab(app.TabGroup2);
            app.MEGTabGr2.Title = 'MEG';
            app.RenameEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RenameEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RenameEventsButtonPushed, true);
            app.RenameEventsButton.Text = 'Rename Events';
            app.DeleteEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.DeleteEventsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventsButtonPushed, true);
            app.DeleteEventsButton.Text = 'Delete Events';
            app.RemoveSimultaneousEventsButton = uibutton(app.MEGTabGr2, 'push');
            app.RemoveSimultaneousEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimultaneousEventsButtonPushed, true);
            app.RemoveSimultaneousEventsButton.Text = 'Remove Simultaneous Events';
            app.DetectArtifactButton = uibutton(app.MEGTabGr2, 'push');
            app.DetectArtifactButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactButtonPushed, true);
            app.DetectArtifactButton.Text = 'Detect Artifact';
            app.SSPButton = uibutton(app.MEGTabGr2, 'push');
            app.SSPButton.ButtonPushedFcn = createCallbackFcn(app, @SSPButtonPushed, true);
            app.SSPButton.Text = 'SSP';
            app.SelectDeselectallButton = uibutton(app.UIFigure, 'push');
            app.SelectDeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectallButtonPushed, true);
            app.SelectDeselectallButton.Text = 'Select/Deselect all';
            app.TabGroup1 = uitabgroup(app.UIFigure);
            app.TabGroup1.SelectionChangedFcn = createCallbackFcn(app, @TabGroup1SelectionChanged, true);
            app.EEGTabGr1 = uitab(app.TabGroup1);
            app.EEGTabGr1.Title = 'EEG';
            app.AddEEGPositionsButton = uibutton(app.EEGTabGr1, 'push');
            app.AddEEGPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @AddEEGPositionsButtonPushed, true);
            app.AddEEGPositionsButton.Text = 'Add EEG Positions';
            app.RefineRegistrationButton = uibutton(app.EEGTabGr1, 'push');
            app.RefineRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineRegistrationButtonPushed, true);
            app.RefineRegistrationButton.Text = 'Refine Registration';
            app.ProjectElectrodesonScalpButton = uibutton(app.EEGTabGr1, 'push');
            app.ProjectElectrodesonScalpButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectElectrodesonScalpButtonPushed, true);
            app.ProjectElectrodesonScalpButton.Text = 'Project Electrodes on Scalp';
            app.DetectHeartbeatsButton = uibutton(app.EEGTabGr1, 'push');
            app.DetectHeartbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectHeartbeatsButtonPushed, true);
            app.DetectHeartbeatsButton.Text = 'Detect Heartbeats';
            app.ImportEventsButton = uibutton(app.EEGTabGr1, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = 'Import Events';
            app.MEGTabGr1 = uitab(app.TabGroup1);
            app.MEGTabGr1.Title = 'MEG';
            app.ConvertEpochstoContinueButton = uibutton(app.MEGTabGr1, 'push');
            app.ConvertEpochstoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertEpochstoContinueButtonPushed, true);
            app.ConvertEpochstoContinueButton.Text = 'Convert Epochs to Continue';
            app.FrequencyAnalysisPanel = uipanel(app.UIFigure);
            app.FrequencyAnalysisPanel.Title = 'Frequency Analysis';
            app.NotchFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.NotchFilterButton.ButtonPushedFcn = createCallbackFcn(app, @NotchFilterButtonPushed, true);
            app.NotchFilterButton.Text = 'Notch Filter';
            app.BandpassFilterButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Band-pass Filter';
            app.PowerSpectrumDensityButton = uibutton(app.FrequencyAnalysisPanel, 'push');
            app.PowerSpectrumDensityButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectrumDensityButtonPushed, true);
            app.PowerSpectrumDensityButton.Text = 'Power Spectrum Density';
            app.Panel = uipanel(app.UIFigure);
            app.OpenBrainstormtoVisualizeGraphsButton = uibutton(app.Panel, 'push');
            app.OpenBrainstormtoVisualizeGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBrainstormtoVisualizeGraphsButtonPushed, true);
            app.OpenBrainstormtoVisualizeGraphsButton.Text = 'Open Brainstorm to Visualize Graphs';
            app.CreateFileHistoryButton = uibutton(app.Panel, 'push');
            app.CreateFileHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFileHistoryButtonPushed, true);
            app.CreateFileHistoryButton.Text = 'Create File History';
            app.TypeofanalysisDropDown = uidropdown(app.Panel);
            app.TypeofanalysisDropDown.Items = {'EEG', 'MEG'};
            app.TypeofanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofanalysisDropDownValueChanged, true);
            app.TypeofanalysisDropDown.Tag = 'FormatDeFichier';
            app.TypeofanalysisDropDown.Value = 'MEG';
            app.TypeofanalysisDropDownLabel = uilabel(app.Panel);
            app.TypeofanalysisDropDownLabel.Text = 'Type of analysis';
            app.AddTagButton = uibutton(app.Panel, 'push');
            app.AddTagButton.ButtonPushedFcn = createCallbackFcn(app, @AddTagButtonPushed, true);
            app.AddTagButton.Text = 'Add Tag';
            app.UpdateEventsButton = uibutton(app.Panel, 'push');
            app.UpdateEventsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEventsButtonPushed, true);
            app.UpdateEventsButton.Text = 'Update Events';
            app.SubjectStudyPanel = uipanel(app.UIFigure);
            app.SubjectStudyPanel.Title = 'Subject/Study';
            app.CreateSubjectButton = uibutton(app.SubjectStudyPanel, 'push');
            app.CreateSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSubjectButtonPushed, true);
            app.CreateSubjectButton.Text = 'Create Subject';
            app.DeleteStudyButton = uibutton(app.SubjectStudyPanel, 'push');
            app.DeleteStudyButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStudyButtonPushed, true);
            app.DeleteStudyButton.Text = 'Delete Study';
            app.ReviewRawFilesButton = uibutton(app.SubjectStudyPanel, 'push');
            app.ReviewRawFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewRawFilesButtonPushed, true);
            app.ReviewRawFilesButton.Text = 'Review Raw Files';
            app.ProtocolPanel = uipanel(app.UIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.CreateProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.CreateProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProtocolButtonPushed, true);
            app.CreateProtocolButton.Text = 'Create Protocol';
            app.LoadProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProtocolButtonPushed, true);
            app.LoadProtocolButton.Text = 'Load Protocol';
            app.DeleteProtocolButton = uibutton(app.ProtocolPanel, 'push');
            app.DeleteProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProtocolButtonPushed, true);
            app.DeleteProtocolButton.Text = 'Delete Protocol';
            app.EEGHyperlink = uihyperlink(app.UIFigure);
            app.EEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials/Epilepsy';
            app.EEGHyperlink.Text = 'Brainstorm EEG Analysis Tutorial';
            app.MEGHyperlink = uihyperlink(app.UIFigure);
            app.MEGHyperlink.URL = 'https://neuroimage.usc.edu/brainstorm/Tutorials';
            app.MEGHyperlink.Text = 'Brainstorm MEG Analysis Tutorial';
            app.HistoryPanel = uipanel(app.UIFigure);
            app.HistoryPanel.Title = 'History';
            app.EventsDetectedPanel = uipanel(app.UIFigure);
            app.EventsDetectedPanel.Title = 'Events Detected';
            app.TESTINGButton = uibutton(app.UIFigure, 'push');
            app.TESTINGButton.ButtonPushedFcn = createCallbackFcn(app, @TESTINGButtonPushed, true);
            app.TESTINGButton.Text = 'TESTING';
            app.ExporttoEDFButton = uibutton(app.UIFigure, 'push');
            app.ExporttoEDFButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoEDFButtonPushed, true);
            app.ExporttoEDFButton.Text = 'Export to EDF';
            app.ProvenancefileButton = uibutton(app.UIFigure, 'push');
            app.ProvenancefileButton.ButtonPushedFcn = createCallbackFcn(app, @ProvenancefileButtonPushed, true);
            app.ProvenancefileButton.Text = 'Provenance file';
            app.ConverttoBIDSButton = uibutton(app.UIFigure, 'push');
            app.ConverttoBIDSButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoBIDSButtonPushed, true);
            app.ConverttoBIDSButton.Text = 'Convert to BIDS';
            app.CreateEventFiletsvButton = uibutton(app.UIFigure, 'push');
            app.CreateEventFiletsvButton.ButtonPushedFcn = createCallbackFcn(app, @CreateEventFiletsvButtonPushed, true);
            app.CreateEventFiletsvButton.Text = 'Create Event File (.tsv)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_MEG_ANALYSIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end