classdef Pipeline_Builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofComponentsEditField   matlab.ui.control.NumericEditField
        NumberofComponentsEditFieldLabel  matlab.ui.control.Label
        PipelineTypeDropDown          matlab.ui.control.DropDown
        PipelineTypeDropDownLabel     matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        ChooseCapListBox              matlab.ui.control.ListBox
        ChooseCapListBoxLabel         matlab.ui.control.Label
        AddEEGPositionGroup           matlab.ui.container.ButtonGroup
        ImportFileButton              matlab.ui.control.RadioButton
        UseDefaultPatternButton       matlab.ui.control.RadioButton
        SavePipelineButton            matlab.ui.control.Button
        BandPassFrequencesPanel       matlab.ui.container.Panel
        HighCutOffHzEditField         matlab.ui.control.NumericEditField
        HighCutOffHzEditFieldLabel    matlab.ui.control.Label
        LowCutOffHzEditField          matlab.ui.control.NumericEditField
        LowCutOffHzEditFieldLabel     matlab.ui.control.Label
        NotchGroup                    matlab.ui.container.ButtonGroup
        Frequence                     matlab.ui.control.TextArea
        SpecificFrequenceButton       matlab.ui.control.RadioButton
        EuropeButton                  matlab.ui.control.RadioButton
        NorthAmericaButton            matlab.ui.control.RadioButton
        ICACheckBox                   matlab.ui.control.CheckBox
        AverageReferenceCheckBox      matlab.ui.control.CheckBox
        PowerSpectrumDensityCheckBox  matlab.ui.control.CheckBox
        BandPassFilterCheckBox        matlab.ui.control.CheckBox
        NotchFilterCheckBox           matlab.ui.control.CheckBox
        ProjectElectrodeonScalpCheckBox  matlab.ui.control.CheckBox
        RefineRegistrationCheckBox    matlab.ui.control.CheckBox
        AddEEGPositionCheckBox        matlab.ui.control.CheckBox
        BrowseButton                  matlab.ui.control.Button
        SaveinFolderEditField         matlab.ui.control.EditField
        SaveinFolderEditFieldLabel    matlab.ui.control.Label
        PipelineNameEditField         matlab.ui.control.EditField
        PipelineNameEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Description
        
        Path = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/';
        
        Pipeline = struct();
        
        AddEEGPosition = struct();
        RefineRegistration = struct();
        ProjectElectrodeOnScalp = struct();
        NotchFilter = struct();
        BandPassFilter = struct();
        PowerSpectrumDensity = struct();
        AverageReference = struct();
        ICA = struct();
    end
    
    methods (Access = private)
        
        function param = getNotchFilterParameters(app)
            
            % Get Notch Filter Parameters
            switch app.NotchGroup.SelectedObject
                case app.EuropeButton
                    param = [50, 100, 150, 200];
                    
                case app.NorthAmericaButton
                    param = [60, 120, 180, 240];
                    
                case app.SpecificFrequenceButton
                    % Assuming frequences are separated by commas.
                    frequences = split(app.Frequence.Value, ',');
                    nbFrequence = length(frequences);
                     
                    param = zeros(1, nbFrequence);
                    for i = 1:nbFrequence
                        param(i) = string(frequences{i});
                    end

                    return
            end
            
        end
        
        function EEGPositionCapStructure = getEEGPositionParameters(app)
            
            % Get EEG Position Parameters
            
            switch app.ChooseCapListBox.Value
                case "Colin27: BrainProducts EasyCap 128"
                    capNumber = 22;
                otherwise
                    capNumber = 0;
            end

            EEGPositionCapStructure.Cap = app.ChooseCapListBox.Value;
            EEGPositionCapStructure.CapNumber = capNumber;
            
        end
        
        function processStructure = getPipelineProcesses(app)
            
            processStructure = struct();
            
            if (app.RefineRegistrationCheckBox.Value)
                processStructure.RefineRegistration.ToRun = true;
            end
            
            if (app.ProjectElectrodeonScalpCheckBox.Value)
                processStructure.ProjectElectrodeOnScalp.ToRun = true;
            end
            
            if (app.AddEEGPositionCheckBox.Value)
                processStructure.AddEEGPosition.ToRun = true;
                processStructure.AddEEGPosition.FileType = app.AddEEGPositionGroup.SelectedObject.Text;
                processStructure.AddEEGPosition.Parameters = app.getEEGPositionParameters;
            end
            
            if (app.NotchFilterCheckBox.Value)
                processStructure.NotchFilter.ToRun = true;
                processStructure.NotchFilter.FrequenceType = app.NotchGroup.SelectedObject;
                processStructure.NotchFilter.Parameters = app.getNotchFilterParameters;
            end
            
            if (app.BandPassFilterCheckBox.Value)
                processStructure.BandPassFilter.ToRun = true;
                processStructure.BandPassFilter.Parameters = [app.LowCutOffHzEditField.Value, app.HighCutOffHzEditField.Value];
            end
            
            if (app.PowerSpectrumDensityCheckBox.Value)
                processStructure.PowerSpectrumDensity.ToRun = true;
            end
            
            if (app.AverageReferenceCheckBox.Value)
                processStructure.AverageReference.ToRun  = true;
            end
            
            if (app.ICACheckBox.Value)
                processStructure.ICA.ToRun = true;
                processStructure.ICA.NumberOfComponents = app.NumberofComponentsEditField.Value;
            end
            
        end
        
        function [] = fillCheckBoxWithStructure(app, pipelineStructure)
            
            fields = fieldnames(pipelineStructure.Pipeline.Processes);
            
            for i = 1:length(fields)
                
                field = fields{i};
                
                if field == "RefineRegistration"
                    app.RefineRegistrationCheckBox.Value = true;
                
                
                elseif field == "AddEEGPosition"
                    app.AddEEGPositionCheckBox.Value = true;

                              
                elseif field == "ProjectElectrodeOnScalp"
                    app.ProjectElectrodeonScalpCheckBox.Value = true;
                    
                    
                elseif field == "NotchFilter"
                    app.NotchFilterCheckBox.Value = true;
                    
                    strFrequenceType = pipelineStructure.Pipeline.Processes.NotchFilter.FrequenceType.Text;
                    
                    if (contains(strFrequenceType, 'Europe'))
                        app.EuropeButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'America'))
                        app.NorthAmericaButton.Value = true;
                        
                    elseif (contains(strFrequenceType, 'Frequence'))
                       app.SpecificFrequenceButton.Value = true;
                       app.NotchGroupSelectionChanged;
                       
                       frequences = pipelineStructure.Pipeline.Processes.NotchFilter.Parameters;
                       app.Frequence.Value = '';
                       for j = 1:length(frequences)
                            app.Frequence.Value = strcat(app.Frequence.Value, sprintf('%.0f', frequences(j)), ', ');
                       end
                       app.Frequence.Value = app.Frequence.Value{1}(1:end-1);
                      
                        
                    end
                    
                elseif field == "BandPassFilter"
                    app.BandPassFilterCheckBox.Value = true;
                    app.LowCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(1);
                    app.HighCutOffHzEditField.Value = pipelineStructure.Pipeline.Processes.BandPassFilter.Parameters(2);


                elseif field == "PowerSpectrumDensity"
                    app.PowerSpectrumDensityCheckBox.Value = true;
                    
                    
                elseif field == "AverageReference"
                    app.AverageReferenceCheckBox.Value = true;   
                    
                    
                elseif field == "ICA"
                    app.ICACheckBox.Value = true;
                    app.NumberofComponentsEditField.Value = pipelineStructure.Pipeline.Processes.ICA.NumberOfComponents;
                
                end
                
                app.refreshVisibility;
                
            end
            
            
        end
        
        function [] = refreshVisibility(app)
            
            app.AddEEGPositionCheckBoxValueChanged;
            app.NotchFilterCheckBoxValueChanged;
            app.BandPassFilterCheckBoxValueChanged;
            app.ICACheckBoxValueChanged;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/');
            app.SaveinFolderEditField.Value = path;
            
            fieldPosition = app.SaveinFolderEditField.Position;
            assignin("base", "long", length(path))
            app.SaveinFolderEditField.Position = [fieldPosition(1), fieldPosition(2), length(path) + 300, fieldPosition(4)];
        end

        % Value changed function: AddEEGPositionCheckBox
        function AddEEGPositionCheckBoxValueChanged(app, event)

            
            if app.AddEEGPositionCheckBox.Value
                
                app.AddEEGPositionGroup.Visible = true;
                app.AddEEGPositionGroupSelectionChanged;
                
            else
                
                app.AddEEGPositionGroup.Visible = false;
                app.ChooseCapListBox.Visible = false;
                
            end
            
        end

        % Value changed function: NotchFilterCheckBox
        function NotchFilterCheckBoxValueChanged(app, event)
 
            if app.NotchFilterCheckBox.Value
                
                app.NotchGroup.Visible = true;
               
            else
                
                app.NotchGroup.Visible = false;
                
            end
            
        end

        % Value changed function: BandPassFilterCheckBox
        function BandPassFilterCheckBoxValueChanged(app, event)

            if app.BandPassFilterCheckBox.Value
                
                app.BandPassFrequencesPanel.Visible = true;
               
            else
                
                app.BandPassFrequencesPanel.Visible = false;
                
            end
            
        end

        % Selection changed function: NotchGroup
        function NotchGroupSelectionChanged(app, event)
            switch app.NotchGroup.SelectedObject
                
                case app.EuropeButton
                    app.Frequence.Visible = false;
                    
                case app.NorthAmericaButton
                    app.Frequence.Visible = false;
                    
                case app.SpecificFrequenceButton
                    app.Frequence.Visible = true;
                    
            end
        end

        % Selection changed function: AddEEGPositionGroup
        function AddEEGPositionGroupSelectionChanged(app, event)
            switch app.AddEEGPositionGroup.SelectedObject
                
                case app.UseDefaultPatternButton
                    app.ChooseCapListBox.Visible = true;
                    
                case app.ImportFileButton
                    app.ChooseCapListBox.Visible = false;
            end
        end

        % Value changed function: Frequence
        function FrequenceValueChanged(app, event)
            value = app.Frequence.Value;
            
            position = app.Frequence.Position;
            
            app.Frequence.Position = [position(1) position(2) length(value{1}) + 100 position(4)];
            
        end

        % Value changed function: PipelineNameEditField
        function PipelineNameEditFieldValueChanged(app, event)
            value = app.PipelineNameEditField.Value;
            
            assignin("base", "value", value);
            
            position = app.PipelineNameEditField.Position;
            
            app.PipelineNameEditField.Position = [position(1) position(2) length(value) + 200 position(4)];
        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)

            
            app.Pipeline = struct();
            
            app.Pipeline.Name = app.PipelineNameEditField.Value;
            
            app.Pipeline.Folder = app.SaveinFolderEditField.Value;
 
            app.Pipeline.Processes = app.getPipelineProcesses;
            
            app.Pipeline.Type = app.PipelineTypeDropDown.Value;


            Pipeline = app.Pipeline;
            
            cd(app.SaveinFolderEditField.Value);
            save(strcat(app.PipelineNameEditField.Value, '.mat'), "Pipeline");
            cd('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin');
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file', app.Path);
            
            app.PipelineNameEditField.Value = fileName(1:end-4);
            app.SaveinFolderEditField.Value = folderPath;

            app.Pipeline = struct();
            app.Pipeline = load(strcat(folderPath, fileName));
            
            
            app.fillCheckBoxWithStructure(app.Pipeline);
            
        end

        % Value changed function: ICACheckBox
        function ICACheckBoxValueChanged(app, event)
            
           if (app.ICACheckBox.Value)              
               app.NumberofComponentsEditField.Visible = true;             
           else
               app.NumberofComponentsEditField.Visible = false;
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PipelineNameEditFieldLabel = uilabel(app.UIFigure);
            app.PipelineNameEditFieldLabel.Text = 'Pipeline Name';
            app.PipelineNameEditField = uieditfield(app.UIFigure, 'text');
            app.PipelineNameEditField.ValueChangedFcn = createCallbackFcn(app, @PipelineNameEditFieldValueChanged, true);
            app.SaveinFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SaveinFolderEditFieldLabel.Text = 'Save in Folder';
            app.SaveinFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AddEEGPositionCheckBox = uicheckbox(app.UIFigure);
            app.AddEEGPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddEEGPositionCheckBoxValueChanged, true);
            app.AddEEGPositionCheckBox.Text = 'Add EEG Position';
            app.RefineRegistrationCheckBox = uicheckbox(app.UIFigure);
            app.RefineRegistrationCheckBox.Text = 'Refine Registration';
            app.ProjectElectrodeonScalpCheckBox = uicheckbox(app.UIFigure);
            app.ProjectElectrodeonScalpCheckBox.Text = 'Project Electrode on Scalp';
            app.NotchFilterCheckBox = uicheckbox(app.UIFigure);
            app.NotchFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterCheckBoxValueChanged, true);
            app.NotchFilterCheckBox.Text = 'Notch Filter';
            app.BandPassFilterCheckBox = uicheckbox(app.UIFigure);
            app.BandPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterCheckBoxValueChanged, true);
            app.BandPassFilterCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityCheckBox = uicheckbox(app.UIFigure);
            app.PowerSpectrumDensityCheckBox.Text = 'Power Spectrum Density';
            app.AverageReferenceCheckBox = uicheckbox(app.UIFigure);
            app.AverageReferenceCheckBox.Text = 'Average Reference';
            app.ICACheckBox = uicheckbox(app.UIFigure);
            app.ICACheckBox.ValueChangedFcn = createCallbackFcn(app, @ICACheckBoxValueChanged, true);
            app.ICACheckBox.Text = 'ICA';
            app.NotchGroup = uibuttongroup(app.UIFigure);
            app.NotchGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchGroupSelectionChanged, true);
            app.NotchGroup.Title = 'Button Group';
            app.NotchGroup.Visible = 'off';
            app.NorthAmericaButton = uiradiobutton(app.NotchGroup);
            app.NorthAmericaButton.Text = 'North America (60Hz, 120Hz, 180Hz, 240Hz)';
            app.NorthAmericaButton.Value = true;
            app.EuropeButton = uiradiobutton(app.NotchGroup);
            app.EuropeButton.Text = 'Europe (50Hz, 100Hz, 150Hz, 200Hz)';
            app.SpecificFrequenceButton = uiradiobutton(app.NotchGroup);
            app.SpecificFrequenceButton.Text = 'Specific Frequence:';
            app.Frequence = uitextarea(app.NotchGroup);
            app.Frequence.ValueChangedFcn = createCallbackFcn(app, @FrequenceValueChanged, true);
            app.Frequence.Visible = 'off';
            app.BandPassFrequencesPanel = uipanel(app.UIFigure);
            app.BandPassFrequencesPanel.Title = 'Band Pass Frequences';
            app.BandPassFrequencesPanel.Visible = 'off';
            app.LowCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.HighCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.SavePipelineButton = uibutton(app.UIFigure, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.AddEEGPositionGroup = uibuttongroup(app.UIFigure);
            app.AddEEGPositionGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEEGPositionGroupSelectionChanged, true);
            app.AddEEGPositionGroup.Title = 'Button Group2';
            app.AddEEGPositionGroup.Visible = 'off';
            app.UseDefaultPatternButton = uiradiobutton(app.AddEEGPositionGroup);
            app.UseDefaultPatternButton.Text = 'Use Default Pattern';
            app.UseDefaultPatternButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.AddEEGPositionGroup);
            app.ImportFileButton.Text = 'Import File';
            app.ChooseCapListBoxLabel = uilabel(app.UIFigure);
            app.ChooseCapListBoxLabel.Visible = 'off';
            app.ChooseCapListBoxLabel.Text = 'Choose Cap:';
            app.ChooseCapListBox = uilistbox(app.UIFigure);
            app.ChooseCapListBox.Items = {'Colin27: BrainProducts EasyCap 128'};
            app.ChooseCapListBox.Visible = 'off';
            app.ChooseCapListBox.Value = 'Colin27: BrainProducts EasyCap 128';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PipelineTypeDropDownLabel = uilabel(app.UIFigure);
            app.PipelineTypeDropDownLabel.Text = 'Pipeline Type';
            app.PipelineTypeDropDown = uidropdown(app.UIFigure);
            app.PipelineTypeDropDown.Items = {'EEG', 'MEG'};
            app.PipelineTypeDropDown.Value = 'EEG';
            app.NumberofComponentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofComponentsEditFieldLabel.Visible = 'off';
            app.NumberofComponentsEditFieldLabel.Text = 'Number of Components';
            app.NumberofComponentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofComponentsEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pipeline_Builder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end